classdef graficky_editor_fotek < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GRAFICKEDITORFOTEKLabel  matlab.ui.control.Label
        PanelTools               matlab.ui.container.Panel
        LabelInfo                matlab.ui.control.Label
        ButtonFullImage          matlab.ui.control.Button
        ButtonZoomOut            matlab.ui.control.Button
        ButtonZoomIn             matlab.ui.control.Button
        ContrastLabel_2          matlab.ui.control.Label
        PanelFiltersAndEfects    matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        ButtonSkica              matlab.ui.control.Button
        ButtonSharpen            matlab.ui.control.Button
        ButtonFlip               matlab.ui.control.Button
        ButtonSepia              matlab.ui.control.Button
        ButtonHue                matlab.ui.control.Button
        ButtonBlur               matlab.ui.control.Button
        ButtonVignette           matlab.ui.control.Button
        ButtonTrim               matlab.ui.control.Button
        ButtonRotateRight        matlab.ui.control.Button
        ButtonRotateLeft         matlab.ui.control.Button
        ButtonInvert             matlab.ui.control.Button
        ButtonBlackAndWhite      matlab.ui.control.Button
        SliderSaturate           matlab.ui.control.Slider
        SytostLabel              matlab.ui.control.Label
        SliderContrast           matlab.ui.control.Slider
        ContrastLabel            matlab.ui.control.Label
        SliderBright             matlab.ui.control.Slider
        JasSliderLabel           matlab.ui.control.Label
        SliderOpacity            matlab.ui.control.Slider
        ButtonReset              matlab.ui.control.Button
        ButtonSave               matlab.ui.control.Button
        ButtonLoad               matlab.ui.control.Button
        PanelImage               matlab.ui.container.Panel
        ImageAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        OriginalImage; % Záloha obrázku (pro reset tlačítko)
        ProcessedImage; % Mezivrstva (uložení filtrů - tlačítek)
        CurrentImage; % Výsledek (uložení sliderů + tlačítka)
    end
    
    methods (Access = private)
        
        function updateDisplay(app)

            if isempty(app.CurrentImage)
                return;
            end
            imshow(app.CurrentImage, 'Parent', app.ImageAxes);
            % zjištuju rozměry vloženého obrázku, které vypíšu do textového pole
            [radky, sloupce] = size(app.CurrentImage);
            app.LabelInfo.Text = ['Rozměry: ' num2str(sloupce) 'x' num2str(radky)];
        end
         
        
        function applyAllSliders(app)

            if isempty(app.ProcessedImage)
                return;
            end

            % 1. Img s filtry, získání hodnot 0.0 až 1.0
            img = double(app.ProcessedImage) / 255;
            
            % 2. SYTOST, převádím do HSV, vynásobím kanál sytosti a vrátím se do RGB
            if app.SliderSaturate.Value ~= 0
                imgHSV = rgb2hsv(img);
                satFactor = (app.SliderSaturate.Value / 100) + 1;
                imgHSV(:,:,2) = imgHSV(:,:,2) * satFactor;
                imgHSV(imgHSV > 1) = 1;
                img = hsv2rgb(imgHSV);
            end
            
            % 3. JAS, přičtu hodnotu slideru k pixelům
            jas = app.SliderBright.Value / 255; 
            img = img + jas;
            
            % 4. KONTRAST, použiju vzorec = (pixel - 0.5) * kontrast + 0.5, 
            % kterým roztahuju hodnoty od středu šedi
            if app.SliderContrast.Value ~= 1
                kontrast = app.SliderContrast.Value;
                img = (img - 0.5) * kontrast + 0.5;
            end
            
            img(img < 0) = 0;
            img(img > 1) = 1;
            
            finalImg = uint8(img * 255);
            
            % 5. PRŮHLEDNOST, vypočítám Alpha blending, neboli smíchám
            % obrázek s bílým pozadím podle procent
            if app.SliderOpacity.Value ~= 100
                alpha = (app.SliderOpacity.Value + 100) / 200;
                whiteBg = 255 * ones(size(finalImg), 'uint8');
                finalImg = finalImg * alpha + whiteBg * (1 - alpha);
            end
            
            app.CurrentImage = finalImg;
            updateDisplay(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ButtonLoad
        function ButtonLoadPushed(app, event)

            [file, path] = uigetfile({'*.jpg;*.png;', 'Obrázky'});
            
            if isequal(file, 0)
                return; 
            end
            
            fullpath = fullfile(path, file);
            img = imread(fullpath);
            
            % uložím obrázek do všech tří proměnných
            app.OriginalImage = img;
            app.ProcessedImage = img;
            app.CurrentImage = img;

            % nastaveni sliderů na defaultní hodnoty
            app.SliderBright.Value = 0;
            app.SliderContrast.Value = 1;
            app.SliderSaturate.Value = 0;
            app.SliderOpacity.Value = 100;
            
            updateDisplay(app);
        end

        % Button pushed function: ButtonBlackAndWhite
        function ButtonBlackAndWhitePushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end

            if size(app.ProcessedImage, 3) == 3
                % převod obrázku na šedou
                gray = rgb2gray(app.ProcessedImage);
                % ošetření aby fungoval slider pro sytost > udělám z šedého obrázku
                % jakoby znovu barevný
                grayRGB = cat(3, gray, gray, gray);
                app.ProcessedImage = grayRGB;
                applyAllSliders(app);
            end

        end

        % Button pushed function: ButtonReset
        function ButtonResetPushed(app, event)
            if isempty(app.OriginalImage)
                return;
            end
            
            app.ProcessedImage = app.OriginalImage;
            app.CurrentImage = app.OriginalImage;
            
            % reset sliderů na defualtní hodnoty
            app.SliderBright.Value = 0;     
            app.SliderContrast.Value = 1;    
            app.SliderSaturate.Value = 0;  
            app.SliderOpacity.Value = 100;

            updateDisplay(app);
        end

        % Button pushed function: ButtonRotateLeft
        function ButtonRotateLeftPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end
            % otočení o 90 stupnů
            app.ProcessedImage = rot90(app.ProcessedImage, 1);
            applyAllSliders(app);
        end

        % Button pushed function: ButtonRotateRight
        function ButtonRotateRightPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end
            
            app.ProcessedImage = rot90(app.ProcessedImage, -1);
            applyAllSliders(app);
        end

        % Button pushed function: ButtonSave
        function ButtonSavePushed(app, event)
            if isempty(app.ProcessedImage)
                uialert(app.UIFigure, 'Nejdříve musíte načíst obrázek!', 'Chyba');
                return;
            end
            
            filter = {'*.jpg', 'JPEG Obrázek (*.jpg)'; ...
                      '*.png', 'PNG Obrázek (*.png)';};
            
            % otevření dialogu pro uložení
            [file, path] = uiputfile(filter, 'Uložit upravený obrázek');
            
            if isequal(file, 0)
                return; 
            end
            
            fullpath = fullfile(path, file);
            
            try
                imwrite(app.CurrentImage, fullpath);
                uialert(app.UIFigure, ['Obrázek byl uložen do: ' file], 'Hotovo');
            catch exception
                uialert(app.UIFigure, exception.message, 'Chyba při ukládání');
            end
        end

        % Button pushed function: ButtonBlur
        function ButtonBlurPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end

            % vytvořím konvoluční masku, matice 5x5 pixelů
            img = double(app.ProcessedImage);
            [h, w, c] = size(img);
            rozmazany = zeros(h, w, c);
            kernel = ones(5) / 25; 
            
            for k = 1:c
                % díky conv2 - projedu obrázek a zprůměruju okolí každého
                % pixelu, tím udělám rozmazání
                rozmazany(:,:,k) = conv2(img(:,:,k), kernel, 'same');
            end
            
            app.ProcessedImage = uint8(rozmazany);
            applyAllSliders(app);
        end

        % Button pushed function: ButtonInvert
        function ButtonInvertPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end
            % odečtu hodnotu od maxima (255 - pixel), tím se bílá stane černou
            app.ProcessedImage = 255 - app.ProcessedImage;
            applyAllSliders(app);
        end

        % Button pushed function: ButtonFlip
        function ButtonFlipPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end
            
            app.ProcessedImage = fliplr(app.ProcessedImage); 
            applyAllSliders(app);
        end

        % Value changed function: SliderSaturate
        function SliderSaturateValueChanged(app, event)
            applyAllSliders(app);
        end

        % Button pushed function: ButtonSepia
        function ButtonSepiaPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end

            img = double(app.ProcessedImage) / 255;
            
            if size(img, 3) == 1
                img = cat(3, img, img, img);
            end
            
            % maticové násobení
            R = img(:,:,1);
            G = img(:,:,2);
            B = img(:,:,3);
            % každý pixel přepočítám podle vzorce (např. Červená = 0.393*R + 0.769*G + 0.189*B)
            % tím vznikne hnědý nádech
            newR = (R * 0.393) + (G * 0.769) + (B * 0.189);
            newG = (R * 0.349) + (G * 0.686) + (B * 0.168);
            newB = (R * 0.272) + (G * 0.534) + (B * 0.131);
            
            sepiaImg = cat(3, newR, newG, newB);
            
            sepiaImg(sepiaImg > 1) = 1;
            
            app.ProcessedImage = uint8(sepiaImg * 255);

            applyAllSliders(app);
        end

        % Value changed function: SliderBright
        function SliderBrightValueChanged(app, event)
            applyAllSliders(app);
        end

        % Value changed function: SliderContrast
        function SliderContrastValueChanged(app, event)
            applyAllSliders(app);           
        end

        % Button pushed function: ButtonHue
        function ButtonHuePushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end
            
            img = app.ProcessedImage;
            %  kontroluju jestli má obrázek tři barevné vrstvy
            if size(img, 3) == 3
                % pomocné proměnné do kterých uložím původní kanály, abych
                % je mohl prohodit > Channel Swapping (prohození kanálů)
                originalR = img(:,:,1);
                originalG = img(:,:,2);
                originalB = img(:,:,3);
                
                % vznik falešnýh barev
                img(:,:,1) = originalG; % R > G
                img(:,:,2) = originalB; % G > B
                img(:,:,3) = originalR; % B > R
                
                app.ProcessedImage = img;
                applyAllSliders(app);
            end
        end

        % Value changed function: SliderOpacity
        function SliderOpacityValueChanged(app, event)
            applyAllSliders(app);
            
        end

        % Button pushed function: ButtonVignette
        function ButtonVignettePushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end
            
            [rows, cols, ~] = size(app.ProcessedImage);
            [cx, cy] = meshgrid(1:cols, 1:rows);
            centerX = cols / 2;
            centerY = rows / 2;
            radius = max(rows, cols) / 1.5;
            % výpočtu vzdálenost každého bodu od sředu (Pythagorova věta)
            dist = sqrt((cx - centerX).^2 + (cy - centerY).^2);

            % vytvořím masku, která je uprostřed 1 (světlá) a v rohách tmavne k 0
            % touto maskou vynásobí obrázek, práce s maticí
            mask = 1 - (dist / radius);
            mask(mask < 0) = 0; mask(mask > 1) = 1;
            
            img = double(app.ProcessedImage);
            img(:,:,1) = img(:,:,1) .* mask;
            img(:,:,2) = img(:,:,2) .* mask;
            img(:,:,3) = img(:,:,3) .* mask;
            
            app.ProcessedImage = uint8(img);
            applyAllSliders(app);
        end

        % Button pushed function: ButtonTrim
        function ButtonTrimPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end
            
            [rows, cols, ~] = size(app.ProcessedImage);
            % zahodím 10 procent pixelů z každé strany pomocí indexování matice
            margin_y = round(rows * 0.1); 
            margin_x = round(cols * 0.1);
            % indexování matice
            app.ProcessedImage = app.ProcessedImage(margin_y:end-margin_y, margin_x:end-margin_x, :);
            applyAllSliders(app);
        end

        % Button pushed function: ButtonFullImage
        function ButtonFullImagePushed(app, event)
            % reset zobrazení obrázku na původní velikost.
            axis(app.ImageAxes, 'image');
        end

        % Button pushed function: ButtonZoomOut
        function ButtonZoomOutPushed(app, event)
            % změním vlastnosti os grafu
            ax = app.ImageAxes;
            
            xl = ax.XLim;
            yl = ax.YLim;
            
            center_x = mean(xl);
            center_y = mean(yl);
            
            % zvětším rozsah os
            range_x = diff(xl) * 1.2;
            range_y = diff(yl) * 1.2;
            
            ax.XLim = [center_x - range_x/2, center_x + range_x/2];
            ax.YLim = [center_y - range_y/2, center_y + range_y/2];
        end

        % Button pushed function: ButtonZoomIn
        function ButtonZoomInPushed(app, event)

            ax = app.ImageAxes;
            
            xl = ax.XLim;
            yl = ax.YLim;
            
            center_x = mean(xl);
            center_y = mean(yl);
            
            % zmenším rozsah os 
            range_x = diff(xl) * 0.8;
            range_y = diff(yl) * 0.8;
            
            ax.XLim = [center_x - range_x/2, center_x + range_x/2];
            ax.YLim = [center_y - range_y/2, center_y + range_y/2];
        end

        % Button pushed function: ButtonSharpen
        function ButtonSharpenPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end

            % vytvořím  jemně rozmazanou verzi (kernel 3x3)
            img = double(app.ProcessedImage);
            [h, w, c] = size(img);
            sharpened = zeros(h, w, c);
            kernel = ones(3) / 9; 
            
            % síla ostrosti
            amount = 0.8; 
            
            for k = 1:c
                % rozmazání
                blurred = conv2(img(:,:,k), kernel, "same");
                
                % detaily k získání = Originál - Rozmazaný
                details = img(:,:,k) - blurred;
                
                % přičtu detaily k originálu
                sharpened(:,:,k) = img(:,:,k) + (details * amount);
            end
            
            % ořežu hodnoty, aby nepřeletěly 0 a 255
            sharpened(sharpened < 0) = 0;
            sharpened(sharpened > 255) = 255;
            
            app.ProcessedImage = uint8(sharpened);
            applyAllSliders(app);
        end

        % Button pushed function: ButtonSkica
        function ButtonSkicaPushed(app, event)
            if isempty(app.ProcessedImage)
                return;
            end
            
            % potřebuju pro detekci hran black and white obrázek

            img = app.ProcessedImage;
            if size(img, 3) == 3
                imgGray = double(rgb2gray(img));
            else
                imgGray = double(img);
            end
            
            % použiju Sobelovy operátory = (matice pro detekci změn v X a Y směru)
            % - standardní matice pro hledání hran
            Gx_kernel = [-1 0 1; -2 0 2; -1 0 1];
            Gy_kernel = [-1 -2 -1; 0 0 0; 1 2 1];
            
            % aplikuju masky
            grad_x = conv2(imgGray, Gx_kernel, 'same');
            grad_y = conv2(imgGray, Gy_kernel, 'same');
            
            % vypočtu velikosti hrany (Pythagorova věta)
            edges = sqrt(grad_x.^2 + grad_y.^2);
            
            % normalizuju zpět do rozsahu 0-255
            % vydělíme maximem, aby nejjasnější hrana byla bílá
            edges = (edges / max(edges(:))) * 255;
            
            % převod na uint8 a udělám z toho 3 kanály pro RGB
            edgesUint8 = uint8(edges);
            edgeImg = cat(3, edgesUint8, edgesUint8, edgesUint8);
            
            app.ProcessedImage = edgeImg;
            applyAllSliders(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PanelImage = uipanel(app.UIFigure);
            app.PanelImage.Title = 'Náhled';
            app.ImageAxes = uiaxes(app.PanelImage);
            app.ImageAxes.XTick = [];
            app.ImageAxes.YTick = [];
            app.ImageAxes.ZTick = [];
            app.PanelTools = uipanel(app.UIFigure);
            app.PanelTools.Title = 'Nástroje';
            app.ButtonLoad = uibutton(app.PanelTools, 'push');
            app.ButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @ButtonLoadPushed, true);
            app.ButtonLoad.Text = 'Nahrát';
            app.ButtonSave = uibutton(app.PanelTools, 'push');
            app.ButtonSave.ButtonPushedFcn = createCallbackFcn(app, @ButtonSavePushed, true);
            app.ButtonSave.Text = 'Uložit';
            app.ButtonReset = uibutton(app.PanelTools, 'push');
            app.ButtonReset.ButtonPushedFcn = createCallbackFcn(app, @ButtonResetPushed, true);
            app.ButtonReset.Text = 'Reset';
            app.SliderOpacity = uislider(app.PanelTools);
            app.SliderOpacity.Limits = [-100 100];
            app.SliderOpacity.ValueChangedFcn = createCallbackFcn(app, @SliderOpacityValueChanged, true);
            app.JasSliderLabel = uilabel(app.PanelTools);
            app.JasSliderLabel.Text = 'Jas';
            app.SliderBright = uislider(app.PanelTools);
            app.SliderBright.Limits = [-100 100];
            app.SliderBright.ValueChangedFcn = createCallbackFcn(app, @SliderBrightValueChanged, true);
            app.ContrastLabel = uilabel(app.PanelTools);
            app.ContrastLabel.Text = 'Kontrast';
            app.SliderContrast = uislider(app.PanelTools);
            app.SliderContrast.Limits = [1 100];
            app.SliderContrast.ValueChangedFcn = createCallbackFcn(app, @SliderContrastValueChanged, true);
            app.SliderContrast.Value = 1;
            app.SytostLabel = uilabel(app.PanelTools);
            app.SytostLabel.Text = 'Sytost';
            app.SliderSaturate = uislider(app.PanelTools);
            app.SliderSaturate.ValueChangedFcn = createCallbackFcn(app, @SliderSaturateValueChanged, true);
            app.PanelFiltersAndEfects = uipanel(app.PanelTools);
            app.PanelFiltersAndEfects.Title = 'Filtry a Efekty';
            app.GridLayout = uigridlayout(app.PanelFiltersAndEfects);
            app.ButtonBlackAndWhite = uibutton(app.GridLayout, 'push');
            app.ButtonBlackAndWhite.ButtonPushedFcn = createCallbackFcn(app, @ButtonBlackAndWhitePushed, true);
            app.ButtonBlackAndWhite.Text = 'Černobílý';
            app.ButtonInvert = uibutton(app.GridLayout, 'push');
            app.ButtonInvert.ButtonPushedFcn = createCallbackFcn(app, @ButtonInvertPushed, true);
            app.ButtonInvert.Text = 'Negativ';
            app.ButtonRotateLeft = uibutton(app.GridLayout, 'push');
            app.ButtonRotateLeft.ButtonPushedFcn = createCallbackFcn(app, @ButtonRotateLeftPushed, true);
            app.ButtonRotateLeft.Text = 'Otočit vlevo';
            app.ButtonRotateRight = uibutton(app.GridLayout, 'push');
            app.ButtonRotateRight.ButtonPushedFcn = createCallbackFcn(app, @ButtonRotateRightPushed, true);
            app.ButtonRotateRight.Text = 'Otočit vpravo';
            app.ButtonTrim = uibutton(app.GridLayout, 'push');
            app.ButtonTrim.ButtonPushedFcn = createCallbackFcn(app, @ButtonTrimPushed, true);
            app.ButtonTrim.Text = 'Oříznout';
            app.ButtonVignette = uibutton(app.GridLayout, 'push');
            app.ButtonVignette.ButtonPushedFcn = createCallbackFcn(app, @ButtonVignettePushed, true);
            app.ButtonVignette.Text = 'Vinětice';
            app.ButtonBlur = uibutton(app.GridLayout, 'push');
            app.ButtonBlur.ButtonPushedFcn = createCallbackFcn(app, @ButtonBlurPushed, true);
            app.ButtonBlur.Text = 'Rozmazání';
            app.ButtonHue = uibutton(app.GridLayout, 'push');
            app.ButtonHue.ButtonPushedFcn = createCallbackFcn(app, @ButtonHuePushed, true);
            app.ButtonHue.Text = 'Hue';
            app.ButtonSepia = uibutton(app.GridLayout, 'push');
            app.ButtonSepia.ButtonPushedFcn = createCallbackFcn(app, @ButtonSepiaPushed, true);
            app.ButtonSepia.Text = 'Sépie';
            app.ButtonFlip = uibutton(app.GridLayout, 'push');
            app.ButtonFlip.ButtonPushedFcn = createCallbackFcn(app, @ButtonFlipPushed, true);
            app.ButtonFlip.Text = 'Zrcadlit';
            app.ButtonSharpen = uibutton(app.GridLayout, 'push');
            app.ButtonSharpen.ButtonPushedFcn = createCallbackFcn(app, @ButtonSharpenPushed, true);
            app.ButtonSharpen.Text = 'Zostřit';
            app.ButtonSkica = uibutton(app.GridLayout, 'push');
            app.ButtonSkica.ButtonPushedFcn = createCallbackFcn(app, @ButtonSkicaPushed, true);
            app.ButtonSkica.Text = 'Skica';
            app.ContrastLabel_2 = uilabel(app.PanelTools);
            app.ContrastLabel_2.Text = 'Průhlednost';
            app.ButtonZoomIn = uibutton(app.PanelTools, 'push');
            app.ButtonZoomIn.ButtonPushedFcn = createCallbackFcn(app, @ButtonZoomInPushed, true);
            app.ButtonZoomIn.Text = '+';
            app.ButtonZoomOut = uibutton(app.PanelTools, 'push');
            app.ButtonZoomOut.ButtonPushedFcn = createCallbackFcn(app, @ButtonZoomOutPushed, true);
            app.ButtonZoomOut.Text = '-';
            app.ButtonFullImage = uibutton(app.PanelTools, 'push');
            app.ButtonFullImage.ButtonPushedFcn = createCallbackFcn(app, @ButtonFullImagePushed, true);
            app.ButtonFullImage.Text = 'Celý obrázek';
            app.LabelInfo = uilabel(app.PanelTools);
            app.LabelInfo.Text = '';
            app.GRAFICKEDITORFOTEKLabel = uilabel(app.UIFigure);
            app.GRAFICKEDITORFOTEKLabel.Text = 'GRAFICKÝ EDITOR FOTEK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = graficky_editor_fotek

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end